classdef supportgui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InkPanel                        matlab.ui.container.Panel
        previewgras                     matlab.ui.control.StateButton
        recordgras                      matlab.ui.control.StateButton
        gorecink                        matlab.ui.control.CheckBox
        InkvFmmsEditFieldLabel          matlab.ui.control.Label
        InkvF                           matlab.ui.control.NumericEditField
        InkvSmmsEditFieldLabel          matlab.ui.control.Label
        InkvS                           matlab.ui.control.NumericEditField
        writeink                        matlab.ui.control.CheckBox
        SettingsPanel                   matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        LayerthicknessumEditFieldLabel  matlab.ui.control.Label
        Layerthickness                  matlab.ui.control.NumericEditField
        LayersEditFieldLabel            matlab.ui.control.Label
        Layers                          matlab.ui.control.NumericEditField
        LinespacingmmLabel              matlab.ui.control.Label
        Linespacing                     matlab.ui.control.NumericEditField
        ConstructfilesButton            matlab.ui.control.Button
        FluigentPanel                   matlab.ui.container.Panel
        Channel1pressurembarEditFieldLabel  matlab.ui.control.Label
        c1p                             matlab.ui.control.NumericEditField
        Channel2pressurembarEditFieldLabel  matlab.ui.control.Label
        c2p                             matlab.ui.control.NumericEditField
        paxes                           matlab.ui.control.UIAxes
        LEDPanel                        matlab.ui.container.Panel
        previewled                      matlab.ui.control.StateButton
        recordled                       matlab.ui.control.StateButton
        gorecled                        matlab.ui.control.CheckBox
        finalled                        matlab.ui.control.CheckBox
        writeled                        matlab.ui.control.CheckBox
        LEDvSmmsEditFieldLabel          matlab.ui.control.Label
        LEDvS                           matlab.ui.control.NumericEditField
        SupportPanel                    matlab.ui.container.Panel
        previewsup                      matlab.ui.control.StateButton
        recordsup                       matlab.ui.control.StateButton
        gorecsup                        matlab.ui.control.CheckBox
        layer1sup                       matlab.ui.control.CheckBox
        writesup                        matlab.ui.control.CheckBox
        SupportvFmmsEditFieldLabel      matlab.ui.control.Label
        SupportvF                       matlab.ui.control.NumericEditField
        SupportvSmmsEditFieldLabel      matlab.ui.control.Label
        SupportvS                       matlab.ui.control.NumericEditField
        PositionsPanel                  matlab.ui.container.Panel
        LEDbarrieryEditFieldLabel       matlab.ui.control.Label
        withy                           matlab.ui.control.NumericEditField
        StagelipzEditFieldLabel         matlab.ui.control.Label
        lipz                            matlab.ui.control.NumericEditField
        ledy                            matlab.ui.control.NumericEditField
        LEDxyEditFieldLabel             matlab.ui.control.Label
        ledx                            matlab.ui.control.NumericEditField
        supy                            matlab.ui.control.NumericEditField
        SupportxyEditFieldLabel         matlab.ui.control.Label
        supx                            matlab.ui.control.NumericEditField
        inky                            matlab.ui.control.NumericEditField
        InkxyEditFieldLabel             matlab.ui.control.Label
        inkx                            matlab.ui.control.NumericEditField
        ink                             matlab.ui.container.ButtonGroup
        u830                            matlab.ui.control.RadioButton
        u820                            matlab.ui.control.RadioButton
        u825                            matlab.ui.control.RadioButton
        u835                            matlab.ui.control.RadioButton
        typetab                         matlab.ui.container.TabGroup
        PrismTab                        matlab.ui.container.Tab
        DiameterEditFieldLabel          matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        EdgesEditFieldLabel             matlab.ui.control.Label
        EdgesEditField                  matlab.ui.control.NumericEditField
        ThicknessEditFieldLabel         matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        StraightlinesTab                matlab.ui.container.Tab
        LinesEditFieldLabel             matlab.ui.control.Label
        LinesEditField                  matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        WritedirectionSwitchLabel       matlab.ui.control.Label
        WritedirectionSwitch            matlab.ui.control.Switch
        WoodpileTab                     matlab.ui.container.Tab
        LinesEditField_2Label           matlab.ui.control.Label
        LinesEditField_2                matlab.ui.control.NumericEditField
        ThicknessEditField_2Label       matlab.ui.control.Label
        ThicknessEditField_2            matlab.ui.control.NumericEditField
        DsideTab                        matlab.ui.container.Tab
        DlayerTab                       matlab.ui.container.Tab
        DTab                            matlab.ui.container.Tab
        MaxvSinkEditFieldLabel          matlab.ui.control.Label
        MaxvSink                        matlab.ui.control.NumericEditField
        MinvSinkEditFieldLabel          matlab.ui.control.Label
        MinvSink                        matlab.ui.control.NumericEditField
        minslideLabel                   matlab.ui.control.Label
        minslide                        matlab.ui.control.NumericEditField
        maxslideLabel                   matlab.ui.control.Label
        maxslide                        matlab.ui.control.NumericEditField
        dvSLabel                        matlab.ui.control.Label
        dvs                             matlab.ui.control.NumericEditField
        linesLabel                      matlab.ui.control.Label
        lines                           matlab.ui.control.NumericEditField
        TypeDropDownLabel               matlab.ui.control.Label
        runtype                         matlab.ui.control.DropDown
        sup                             matlab.ui.container.ButtonGroup
        c04                             matlab.ui.control.RadioButton
        c12                             matlab.ui.control.RadioButton
        c20                             matlab.ui.control.RadioButton
        l3                              matlab.ui.control.RadioButton
        l4                              matlab.ui.control.RadioButton
    end


    properties (Access = private)
        perio = 0.5;            % time between updates
        pmax = 7500;            % maximum pressure
        fConnect = true;        % means connection succeeded
        hn;                     % fluigent handle number
        timer1;                 % timer object updates fluigent
        ptable = zeros(500,3);  % time, pressure 1, pressure 2
        
        grasOpen = true;
        gsrc;       % grasshopper source
        gras;       % grasshopper camera VideoInput
        
        ledOpen = true; % led camera is open
        lsrc;
        ledcam;
        
        supOpen = true; % support camera is open
        ssrc;
        supcam;         % support camera object
        
        supp = 0;
        inkp = 0;
        
        tempdir = 'D:\polygons';
        basedir = 'D:\polygons';
        matlabdir = 'C:\Users\begle\Documents\MATLAB\Shopbot';
    end

    methods (Access = private)
    
        function turnonsupbuttons(app)
            value = app.layer1sup.Value;
            value2 = app.writesup.Value;          
            if value || value2
                app.SupportvS.Enable = 'on';
                app.SupportvF.Enable = 'on';
            else
                app.SupportvS.Enable = 'off';
                app.SupportvF.Enable = 'off';
            end
        end
        
        function turnonLEDbuttons(app)
            value = app.writeled.Value;
            value2 = app.finalled.Value;          
            if value || value2
                app.LEDvS.Enable = 'on';
            else
                app.LEDvS.Enable = 'off';
            end
        end
        
        function initializeFluigent(app)
            try
                [ app.hn, ~ ] = mfcs_init; %initialize fluigent
            catch
                %if there is an error opening the fluigent
                warning('Could not connect to Fluigent. Check that unit is on.');
                app.fConnect = false; %means connection failed
            end
            if app.fConnect
                fprintf('Opened connection to Fluigent.\n');
                app.c1p.Enable = 'on';
                app.c2p.Enable = 'on';
                app.paxes.Visible = 'on';
                       
                % setup data file
                p1 = app.ptable;
                p1(length(p1),1) = 0; %set start time to 0
                p1(1:length(p1), 1) = p1(length(p1),1)-(length(p1)-1)*(app.perio):(app.perio):p1(length(p1),1); %scale remaining time steps relative to current time 
                app.ptable = p1; %store in p1
                
                app.timer1 = timer;
                app.timer1.ExecutionMode = 'fixedRate';   % Run timer repeatedly
                app.timer1.Period = app.perio;               
                app.timer1.TimerFcn = @(obj, event)timerfxn(app);
                start(app.timer1);
                fprintf('Timer created\n');
                
                plot(app.paxes,p1(:,1),p1(:,2), p1(:,1),p1(:,3)); %update plot
                set(app.paxes, 'ylim', [0 app.pmax]);
            end
        end
        
        function initializeGras(app)
            %%-------------GRAS CAMERA ---------------------------
            %---------------------------------------------------
            try
               gr = videoinput('pointgrey', 1, 'F7_Mono8_960x600_Mode4');
%                 gr = videoinput('pointgrey', 1, 'F7_RGB_960x600_Mode4');
            catch
                app.grasOpen = false;
                warning('Failed to connect to Grasshopper');
            end
            if app.grasOpen
                fprintf('Connected to Grasshopper\n');
                gr.FramesPerTrigger = Inf;
                src = getselectedsource(gr);
%                 src.FrameRate = 40;
                app.gsrc = src;
                app.gras = gr;
                app.previewgras.Enable = 'on';
                app.recordgras.Enable = 'on';
            end
            %%-------------LED CAMERA ---------------------------
            %---------------------------------------------------
            try
                le = videoinput('winvideo', 1, 'YUY2_640x480');
            catch
                app.ledOpen = false;
                warning('Failed to connect to LED camera');
            end
            if app.ledOpen
                fprintf('Connected to LED camera\n');
                le.FramesPerTrigger = Inf;           
                le.ReturnedColorspace = 'rgb';
                src = getselectedsource(le);
%                 src.FrameRate = '15.0000';
                app.lsrc = src;
                app.ledcam = le;
                app.previewled.Enable = 'on';
                app.recordled.Enable = 'on';
            end
            %%-------------SUPPORT CAMERA ---------------------------
            %---------------------------------------------------
            try
                su = videoinput('winvideo', 2, 'MJPG_640x480');
            catch
                app.supOpen = false;
                warning('Failed to connect to support camera');
            end
            if app.grasOpen
                fprintf('Connected to support camera\n');
                su.FramesPerTrigger = Inf;
                su.ReturnedColorspace = 'rgb';
                src = getselectedsource(su);
%                 src.FrameRate = '15.0000';
                app.ssrc = src;
                app.supcam = su;
                app.previewsup.Enable = 'on';
                app.recordsup.Enable = 'on';
            end
        end
                
        function timerfxn(app)
            updateDisplay(app);
        end
        
        function updateDisplay(app)
            p1 = app.ptable;
            p1(1:length(p1)-1, [1 2 3]) = p1(2:length(p1), [1 2 3]); %shift all values up by 1
            p1(length(p1),1) = p1(length(p1)-1, 1) + app.perio; %add new point
            if app.fConnect     %only update plot if fluigent is connected
                [p1(length(p1), 2), ~] = mfcs_read_chan( app.hn, 1 ); %read in new value
                [p1(length(p1), 3), ~] = mfcs_read_chan( app.hn, 2 ); %read in new value
                plot(app.paxes,p1(:,1),p1(:,2), p1(:,1),p1(:,3)); %update plot
            end
            app.ptable = p1;
            if app.fConnect
                watchSBflags(app);
            end
        end
        
        function watchSBflags(app)
            output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
            if output>0 %update pressure to match shopbot switches
                if mod(output, 2)==1
                    output = 1;
                elseif mod(output, 4)==2
                    output = 2;
                else
                    output = 0;
                end
                switch output
                    case 0 %led
                            mfcs_set_auto(app.hn, 0,0); 
                    case 1 %support material
                            mfcs_set_auto(app.hn, 0,2);
                            mfcs_set_auto(app.hn, app.supp, 1);
                    case 2 %ink
                            mfcs_set_auto(app.hn, 0,1);
                            mfcs_set_auto(app.hn, app.inkp, 2);
                end
            end
        end

        %--------------------
        %--------------------
        %--------------------
        %%%CONSTRUCTPRISM
        %--------------------
        %--------------------
        function constructPrism(app)
            n = app.EdgesEditField.Value;
            points = circlePoints(app, n);
            spacing = app.Linespacing.Value/cosd(anglesf(app, n));
            pr = app.DiameterEditField.Value/2; %prism radius (mm)
            iss = 1.1;
            thickness = app.ThicknessEditField.Value;
            snum = thickness;
            innerradii = (pr-snum*iss*spacing-spacing):(spacing*iss):(pr-spacing);
            innerradii(length(innerradii))=pr-spacing;
            outerradii = (pr+thickness*spacing):(spacing):(pr+thickness*spacing + snum*spacing);
            radii = pr:spacing:pr+(thickness-1)*spacing;
            
            offset = outerradii(length(outerradii))*[max(points(:,1)), max(points(:,2))];
            offset = offset - [app.inkx.Value, app.inky.Value];
            
            layers = app.Layers.Value;
            h = app.Layerthickness.Value/1000;
            
            pointsseq = cell(1, 6*layers + 2); %point sequence
            psi = 1; %pointsseq index
            pointsseq{psi}  = openingline(app);
            psi = psi+1;
            %first layer
            if app.layer1sup.Value
                pointsseq{psi} = writeprism(app,sort([innerradii radii outerradii]), app.supx.Value, app.supy.Value, offset, points, h, app.SupportvS.Value, 6, 1);
                psi = psi+1;
                firstheight = 2*h;
                layers = layers+1;
            else
                firstheight = h;
            end
            if ~app.writesup.Value && ~app.writeled.Value && app.writeink.Value
                % just writing ink
                pointsseq{psi} = strcat('J2, ', num2str( points(1,1) + app.inkx.Value), ', ', num2str(points(1,2)+app.inky.Value), '\n');
                psi = psi+1;
                pointsseq{psi} = strcat('MS, ', num2str(app.InkvS.Value), '\n');
                psi = psi+1;
                pointsseq{psi} = ffile2str(app, 2);
                psi = psi+1;
                for z = firstheight:h:layers*h
                    pointsseq{psi} = strcat('JZ, ', num2str(z), '\n');
                    psi = psi+1;
                    pointsseq{psi} = writeprism(app,radii, app.inkx.Value, app.inky.Value, offset, points, z, app.InkvS.Value, 0, 2);
                    psi = psi+1;
                end
            else
                % includes support or led
                liftheight = app.lipz.Value;
                for z = firstheight:h:layers*h 
                    if app.writesup.Value
                        %SUPPORT PRISM
                        pointsseq{psi} = writeprism(app,innerradii, app.supx.Value, app.supy.Value, offset, points, z, app.SupportvS.Value, liftheight, 1);
                        psi = psi+1;
                        pointsseq{psi} = writeprism(app,fliplr(outerradii), app.supx.Value, app.supy.Value, offset, points, z, app.SupportvS.Value, liftheight, 1);
                        psi = psi+1;
                    end
                    if app.writeink.Value
                        %WRITING PRISM
                        pointsseq{psi} = writeprism(app,radii, app.inkx.Value, app.inky.Value, offset, points, z, app.InkvS.Value, liftheight, 2);
                        psi = psi+1;
                        %CURING led
                        if app.writeled.Value
                            pointsseq{psi} = writeledprism(app, radii, offset, points, z, liftheight, 1);
                            psi = psi + 1;
                        end
                    end
                end
            end
            %final cure
            if app.finalled.Value && ~app.writeled.Value
                pointsseq{psi} = strcat('JZ,', num2str(app.lipz.Value), '\n');
                psi = psi+1;
                pointsseq{psi} = writeledprism(app, radii, offset, points, z, num2str(app.lipz.Value), round(layers/2));
                psi = psi + 1;
            end            
            pointsseq{psi} = strcat(ffile2str(app,0), 'JZ, ', num2str(app.lipz.Value), '\nJX, 140\n'); %lower and draw away
            savemsbpfiles(app,pointsseq)
        end
    
        %writeprism is used by constructPrism and constructBlock
        function pointstring = writeprism(app,radii, xi, yi, offset, points, z, vs, liftheight, ffile)
            % radii = list of radii, xi yi is the origin of the nozzle, points is a list of points on the circle
            % z is the print position, vs stage speed
            % liftheight where to lift to at the end, 0 if we're just printing ink continuously through z
            % ffile is 0 led, 1 sup, 2 ink
            pointsseq = cell(1, length(radii)*(1+length(points))+4);
            psi = 1;
            if liftheight>0           
                pointsseq{psi} = strcat('MS,', num2str(vs),'\n');
                psi = psi+1;
            end
            for i=1:length(radii)
                for j=1:length(points)
                    pt = radii(i)*points(j, [1 2]) + [xi, yi] - offset;
                    if i==1 && j==1
                        if liftheight>0
                            pointsseq{psi} = strcat('J2, ', num2str(pt(1)), ', ', num2str(pt(2)),'  ''jump\n');
                            psi = psi + 1;
                            pointsseq{psi} = ffile2str(app,ffile);
                            psi = psi+1;
                            pointsseq{psi} = strcat('JZ,', num2str(z),'\n'); 
                            psi = psi+1;
                        end
                    else
                        pointsseq{psi} = strcat('M2, ', num2str(pt(1)), ', ', num2str(pt(2)),'\n');
                        psi = psi+1;
                    end
                end
            end
            if liftheight>0
                pointsseq{psi} = ffile2str(app,0);
                psi = psi+1;
                pointsseq{psi} = strcat('JZ,', num2str(liftheight), '\n'); %lift during move
            end
            pointstring = strcat(pointsseq{:});
        end
    
        function pointstring = writeledprism(app, radii, offset, points, z, liftheight, rounds)
            pointsseq = cell(rounds+2);
            pointsseq{1} = strcat('JY, ', num2str(app.withy.Value),'\nJX, ', num2str(round(app.ledx.Value)),'\n');
            for i=1:rounds
                pointsseq{i+1} = writeprism(app,radii, app.ledx.Value, app.ledy.Value, offset, points, z, app.LEDvS.Value, liftheight, 0);
            end
            pointsseq{rounds+2} = strcat('JY, ', num2str(app.withy.Value),'\nJX, ', num2str(round(app.supx.Value)),'\n');
            pointstring = strcat(pointsseq{:});
        end
    
            %circlePoints is used by constructrPrism and constructBlock
        function points = circlePoints(~,n)
            angles = pi/n:2*pi/n:(2*pi+pi/n);
            points = [cos(angles); sin(angles)]';
        end

        %--------------------
        %--------------------
        %%%CONSTRUCTSTRAIGHT
        %--------------------
        %--------------------
        function constructStraight(app)
            supspacing = app.Linespacing.Value;
            inkspacing = app.Linespacing.Value;
            spacing = inkspacing + supspacing;
            lines = app.LinesEditField.Value;
            suplines = (supspacing/2):spacing:(lines*spacing + supspacing/2);
            inklines = suplines(1:lines) + spacing/2;
            
            dir = app.WritedirectionSwitch.Value;
            if dir==1
                % write in x
                suplines = app.supy.Value - suplines;
                ledlines = app.ledy.Value - inklines;
                inklines = app.inky.Value - inklines;
            else
                suplines = app.supx.Value - suplines;
                ledlines = app.ledx.Value - inklines;
                inklines = app.inkx.Value - inklines;
            end
            
            layers = app.Layers.Value;
            h = app.Layerthickness.Value/1000;
            l = app.LengthEditField.Value;
            dir = app.WritedirectionSwitch.Value;
            
            pointsseq = cell(1, 5*layers+2); %point sequence
            psi = 1; %pointsseq index
            pointsseq{psi}  = openingline(app);
            psi = psi+1;           
            if app.layer1sup.Value
                pointsseq{psi} = writezigzag(app,sort([suplines (suplines + spacing/2)]), app.supx.Value, app.supy.Value, app.SupportvS.Value, 1, h, app.lipz.Value,0, l, dir);
                psi = psi+1;
                firstheight = 2*h;
                layers = layers+1;
            else
                firstheight = h;
            end
            if ~app.writesup.Value && ~app.writeled.Value && app.writeink.Value
                liftheight = 0;
                pointsseq{psi} = strcat('MS, ', num2str(app.InkvS.Value), '\n');
                psi = psi+1;
                pointsseq{psi} = ffile2str(app, 2);
                psi = psi+1;
                for z = firstheight:h:layers*h
                    pointsseq{psi} = writezigzag(app, inklines, app.inkx.Value, app.inky.Value, app.InkvS.Value, 2, z, liftheight,0, l, dir);
                    psi = psi+1; 
                    pointsseq{psi} = strcat('JZ, ', num2str(z+h), '\n');
                    psi = psi+1;
                    inklines = fliplr(inklines);
                end
            else
                liftheight = app.lipz.Value;             
                for z = firstheight:h:layers*h                 
                    if app.writesup.Value
                        %SUP LINES
                        pointsseq{psi} = writezigzag(app,suplines, app.supx.Value, app.supy.Value, app.SupportvS.Value, 1, z, liftheight, l, dir);
                        psi = psi+1;   
                    end
                    if app.writeink.Value
                        %INK LINES
                        pointsseq{psi} = writezigzag(app,inklines, app.inkx.Value, app.inky.Value, app.InkvS.Value, 2, z, liftheight,0, l, dir);
                        psi = psi+1;  
                        %led LINES
                        if app.writeled.Value
                           pointsseq{psi} = writeledzigzag(app, ledlines, h, liftheight, l, 1, dir);
                           psi = psi+1;
                        end
                    end
                end
            end
            if app.finalled.Value && ~app.writeled.Value
               pointsseq{psi} = strcat('JZ, ', num2str(app.lipz.Value), '\n');
               psi = psi+1;
               pointsseq{psi} = writeledzigzag(app, ledlines, h, app.lipz.Value, l, round(layers/2), dir);
               psi = psi+1;
            end
            pointsseq{psi} = strcat(ffile2str(app,0), 'JZ, ', num2str(app.lipz.Value), '\nJX, 140\n'); %lower and draw away
            savemsbpfiles(app,pointsseq)
        end
    
    %-----------------------------------------------------%
    %----------------------CONSTRUCT 2D-------------------%
    %-----------------------------------------------------%
    
        function construct2D(app)
            fprintf('-----------------\nStarting 2D routine\n');
            scale = inkscale(app);
            fc = app.fConnect;
            inkname = app.ink.SelectedObject.Tooltip;
            if length(inkname)==1
                inkname = inkname{1};
            end


            for speed = app.MinvSink.Value:app.dvs.Value:app.MaxvSink.Value
                prink = round(scale*speed);
                fprintf('Ink pressure %d\n', prink);
                constructslides(app, speed);
                app.inkp = prink;
                for i=app.minslide.Value:app.maxslide.Value
                    folder = strcat(app.basedir,'\', inkname, '_v_', num2str(speed), '_slide_', num2str(i));
                    
                    if ~isfolder(folder)
                        mkdir(folder);
                    end
%                     if ~isfolder(strcat(folder, '\sbpics'))
%                         mkdir(folder);
%                     end
                    ds = datestr(now,'yymmdd_HHMMSS');
                    prefix = app.runtype.Value;
                    if strcmp(prefix,'2D')
                        pref = 'D2_';
                        waittime = 120;
                    elseif strcmp(prefix,'3D')
                        pref = 'D3_';
                        waittime = 0;
                    elseif strcmp(prefix,'3deep')
                        pref = 'deep3_';
                        waittime = 0;
                    else
                        error('Invalid test type.');
                    end
                    basename = strcat(pref, inkname,'_slide_', num2str(i), '_v_', num2str(speed), '_', ds);
                    command = strcat('"C:\Program Files (x86)\ShopBot\ShopBot 3\Sb3.exe" " ', app.matlabdir, '\slide', num2str(i), '.sbp "," ",4," ",0," "2," "," "&');
                    system(command);
                    
                    if waittime>0
                        pause(waittime); 
                        
                        % support
                        output = 9;
                        % watch the switches to know when we're done
                        while output<10
                            output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                                    'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
                            pause(2); 
                        end
                        % when output hits 10, support is done
                    end
                    
                    %ink
                    app.recordgras.Value = true;
                    start(app.gras);
                    fprintf('Starting recording\n');
                    output = 10;
                    pause(60);
                    while output>8
                        output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                                'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
                        pause(5); 
                    end
                    %when output hits 8, ink is done
                    
                    %led
                    app.recordgras.Value = false;
                    stop(app.gras);
                    fprintf('Stopping recording\n');
                    videowrite(app, 'gras', basename);
                    movefile(strcat(app.basedir, '\gras_', basename, '.mp4'), folder);
                    preview(app.gras);
%                     app.fConnect = false;
%                     runpicroutine(app, i, basename, folder);
                    if speed<app.MaxvSink.Value || i<app.maxslide.Value
                        answer = questdlg('Place new slide', 'Slide finished', 'OK', 'ABORT', 'OK');
                        if strcmp(answer, 'ABORT')
                            fprintf('Sequence aborted\n');
                            return;
                        end
                    end
%                     app.fConnect = fc;
                end    
            end

            fprintf('----------\nFinished\n----------\n');
 
        end
    
        function scale = inkscale(app)
            switch app.ink.SelectedObject
                case app.u830
                    scale = 29.83;
                case app.u820
                    scale = 129;
                case app.u825
                    scale = 120;
                case app.u835
                    scale = 37.76;
            end
        end
    
        function constructslides(app, inkvF)
            for i=app.minslide.Value:app.maxslide.Value
                constructslide(app, inkvF, i)
            end
        end
           
        function savesbp(~, pointsseq, filename)
            sbpcode = strcat(pointsseq{:});
            fid = fopen(filename, 'w');
            fprintf(fid, sbpcode);
            fclose(fid);
            fprintf('Exported %s\n', filename);
        end
    
        function line = setvar(~, string, number)
            line = strcat(string, ' = ', num2str(number), '\n');
        end
    
        function constructslide(app, inkvF, slidenum)
            pointsseq = cell(1000);
            
            variables = {{'&vssup', app.SupportvS.Value},...
                {'&vsink', inkvF},...
                {'&vsled', app.LEDvS.Value},...
                {'&liftheight', app.lipz.Value},...
                {'&barriery', app.withy.Value},...
                {'&h', app.Layerthickness.Value/1000},...
                {'&supx', app.supx.Value},...
                {'&supy', app.supy.Value},...
                {'&inkx', app.inkx.Value},...
                {'&inky', app.inky.Value},...
                {'&ledx', app.ledx.Value},...
                {'&ledy', app.ledy.Value}};
            
            for i=1:length(variables)
                pointsseq{i} = setvar(app, variables{i}{1}, variables{i}{2});
            end
            pointsi = pointsseq;
            
            psi = length(variables)+1;
            sbpfile = strcat(app.matlabdir, '\slide', num2str(slidenum), '_base.txt');
            fid = fopen(sbpfile);
            line = fgetl(fid);
            while ~isnumeric(line)
                pointsseq{psi} = strcat(line, '\n');
                psi = psi+1;
                line = fgetl(fid);
            end
            fclose(fid); 
            file = strcat(app.matlabdir, '\slide', num2str(slidenum), '.sbp');
            savesbp(app, pointsseq, file);
            
%             psi = length(variables)+1;
%             pointsseq = pointsi;
%             sbpfile = strcat(app.matlabdir, '\slide', num2str(slidenum), '_pics_base.txt');
%             fid = fopen(sbpfile);
%             line = fgetl(fid);
%             while ~isnumeric(line)
%                 pointsseq{psi} = strcat(line, '\n');
%                 psi = psi+1;
%                 line = fgetl(fid);
%             end
%             fclose(fid); 
%             file = strcat(app.matlabdir, '\slide', num2str(slidenum), '_pics.sbp');
%             savesbp(app, pointsseq, file);
        end
    
        function reconstructslides(app)
           
            %slide 1
            centers = [ 20.1, 7.8; 35.1, 9; 54.5, 10.2; 54.8, 29.4; 34.8, 28.3; 12, 26.7];
            sides = [5, 6, 6, 5, 5, 5];
            edges = [6, 6, 8, 8, 10, 12];
            rewriteSBP(app, edges, centers, sides, 1);
            
            %slide 2
            centers = [24.2, 12.4; 50.4, 14.3;  51, 34.1; 38.9, 33; 24.5, 32.2; 8.9, 31];
            sides = [6,6,3,3,3,3];
            edges = [10, 12, 6, 8, 10, 12];
            rewriteSBP(app, edges, centers, sides, 2);
            
            %slide 3
            centers = [16.4, 22.8; 36, 8.4; 54.6, 10.1; 58.4, 33.4; 42.6, 28.4];
            sides = [8, 4, 4, 4, 8];
            edges = [10, 10, 12, 6, 6];
            rewriteSBP(app, edges, centers, sides, 3);
            
            %slide 4
            centers = [18.2, 20.9;  57.5, 7; 51.3, 26.3];
            sides = [8, 4, 8];
            edges = [12, 8, 8];
            rewriteSBP(app, edges, centers, sides, 4);
        end
    
        function sbpcode = presquare(~, xmin, xmax, ymin, ymax, origin)
            pointsseq = cell(1000);
            psi = 1;
            o = 1;
            pointsseq{psi} = strcat('J2, ', origin{1}, '+', num2str(-xmin), ', ', origin{2}, '+', num2str(-ymin),'\n');
                psi = psi+1;
            for y = ymin:0.3:ymax
                if o == 1
                    x1 = xmin;
                    x2 = xmax;
                else
                    x1 = xmax;
                    x2 = xmin;
                end
                pointsseq{psi} = strcat('M2, ', origin{1}, '+', num2str(-x1), ', ', origin{2}, '+', num2str(-y),'\n',...
                    'M2, ', origin{1}, '+', num2str(-x2), ', ', origin{2}, '+', num2str(-y),'\n');
                psi = psi+1;
                o = -o;
            end
            pointsseq{psi} = 'JZ,&liftheight\n';
            sbpcode = strcat(pointsseq{:});
        end

        function rewriteSBP(app, edges, centers, sides, slidenum)
            radii = edges./(2*sind(anglesf(app, sides)));
            flowcommands = {'SO, 1, 1\nSO, 2, 0\n', 'SO, 1, 1\nSO, 2, 0\n', 'SO, 1, 0\nSO, 2, 1\n', 'SO, 1, 0\nSO, 2, 0\n'};
            collector  = cell(length(radii), 4);
            speeds = {'&vssup', '&vssup', '&vsink', '&vsled'};
            origin = {{'&supx', '&supy'}, {'&supx', '&supy'}, {'&inkx', '&inky'}, {'&ledx', '&ledy'}};
            for i=1:length(radii)
                [collector{i, 1}, collector{i, 2}, collector{i, 3}, collector{i,4}, collector{i,5}] = prismprint(app, sides(i), radii(i),centers(i, :));
            end
            pointsseq = cell(1000);
            psi = 1;

            prefix = app.runtype.Value;
            if strcmp(prefix,'2D')
                indi = 2;
                pointsseq{psi} = strcat('JZ,&liftheight\n',  flowcommands{2});
                psi = psi+1;
            else
                indi = 3;
            end

            %k = 1 (base), k=2 (support), k=3 (ink), k=4 (led), k=5 (pics)
            for k = indi:4
%                 if k==2
%                     pointsseq{psi} = strcat('J2, ', num2str(collector{1, k}{1}(1, 1)),'+', origin{k}{1}, ', ', num2str(collector{1,k}{1}(1,2)),  '+', origin{k}{2},'\nJZ,&h\n', flowcommands{k});
%                 else
%                     pointsseq{psi} = strcat('J2, ', num2str(collector{1, k}(1, 1)), '+', origin{k}{1},', ', num2str(collector{1,k}(1,2)),'+', origin{k}{2}, '\nJZ,&h\n', flowcommands{k});
%                 end
                pointsseq{psi} = strcat('J2, ', origin{k}{1}, '+', num2str(-1), ', ', origin{k}{2}, '+', num2str(-1),'\nJZ,&h\n', flowcommands{k});
                psi = psi+1;
                pointsseq{psi} = strcat('MS, ', num2str(speeds{k}), '\n');
                psi = psi+1;
                if k==2
                    pointsseq{psi} = presquare(app, 1, 10, 1, 2.5, origin{k});
                elseif k==3
                    pointsseq{psi} = presquare(app, 1, 10, 2.5, 4, origin{k});
                end
                psi = psi+1;
                %prisms
                for i=1:length(radii)
                    ck = collector{i,k};
                    if k==2
                        pointsseq{psi} = jumpwritepoints(app, ck{1}, origin{k});
                        if length(ck)>1
                            psi = psi+1;
                            pointsseq{psi} = jumpwritepoints(app, ck{2}, origin{k});
                        end
                    else
                        pointsseq{psi} = jumpwritepoints(app, ck, origin{k});
                    end
                    psi = psi+1;
                end
                if k>1
                    pointsseq{psi} = strcat('SO, 2, 0\nSO, 1, 0\n');
                    psi = psi+1;
                end
                pointsseq{psi} = strcat('JZ,&liftheight\n');
                psi = psi+1;
                if k==3
                    pointsseq{psi} = strcat('JY,&barriery\nJX,&ledx\n');
                    psi = psi+1;
                end
            end
            
            pointsseq{psi} = strcat('JY, &barriery\nJX,&inkx+80\n');
            file = strcat(app.matlabdir, '\slide', num2str(slidenum), '_base.txt');
            savesbp(app, pointsseq, file);
%             writepicroutine(app, {collector{:,5}}, slidenum);
        end
    
        function sbpcode = jumpwritepoints(~, pts, origin)
            %points
            pointsseq = cell(1000);
            psi = 1;
            for j=1:length(pts)
                if j==1
                    c1 = 'J';
                    c2 = strcat('JZ,&h\n'); %jog, go to z on first point
                else
                    c1 = 'M';
                    c2 = '';
                end
                pointsseq{psi} = strcat(c1, '2, ', num2str(pts(j, 1)), '+', origin{1}, ', ', num2str(pts(j,2)), '+', origin{2}, '\n', c2);
                psi = psi+1;
            end
            pointsseq{psi} = strcat('JZ,&h+1\n'); %lift after writing prism
            sbpcode = strcat(pointsseq{:});
        end
    
        function [basepoints, suppoints, inkpoints, ledpoints, picpoints] = prismprint(app, n, radius, xyoffset)
            lines = app.lines.Value;
            d = app.Linespacing.Value;
            spacing = d./cosd(anglesf(app, n));
            
            outerinkedge = radius;
            
            basemin = (outerinkedge - (lines*2-1)*spacing);
            if basemin<spacing
                basemin = spacing;
            end
            basemax = (outerinkedge + spacing*(lines + 1));
            baseradii = basemin:spacing:basemax;
            basepoints = prism(app, n, baseradii, xyoffset);
            
            supouter = (outerinkedge + spacing):spacing:(outerinkedge + (lines+1)*spacing);
            supmin = (outerinkedge - (lines*2-1)*spacing);
            if supmin<spacing
                supmin = spacing;
            end
            supmax1 = (outerinkedge - lines*spacing);
            if supmax1<spacing
                suppoints = {prism(app, n, supouter, xyoffset)};
            else
                suppoints = {prism(app, n, supmin:spacing:supmax1, xyoffset), prism(app, n, supouter, xyoffset)};
            end
            
            inkmin = (outerinkedge - (lines-1)*spacing);
            if inkmin<spacing
                inkmin = spacing;
            end
            inkradii = inkmin:spacing:outerinkedge;
            inkpoints = prism(app, n, inkradii, xyoffset);
            ledpoints = prism(app, n, inkradii, xyoffset);
            picpoints = prism(app, n, [(outerinkedge + inkmin)/2], xyoffset);
        end
    
        function points = prism(~, n, radii, xyoffset)
            angles = (pi/n:2*pi/n:(2*pi+pi/n));
            numpoints = [length(radii)*length(angles),1];
            radii = reshape(radii, [length(radii), 1]);
            points = [reshape((radii*cos(angles))', numpoints), reshape((radii*sin(angles))', numpoints)] + xyoffset;
            points = -points;
        end
    
        function results = picline(~, x, y)
            results = strcat('M2, &inkx + ', num2str(x), ', &inky + ', num2str(y), '\n', ...
                'PAUSE &pausetime2\nSO, 1, 1\nSO, 2, 0\nPAUSE &pausetime\nSO, 1, 0\nSO, 2, 1\n');
        end
    
        function writepicroutine(app, points, slidenum)
            pointsseq = cell(1000);
            psi = 1;
            
            pointsseq{psi} = '&pausetime = 0.75\n&pausetime2 = 1.5\n';
            psi = psi+1;
            
            pointsseq{psi} = 'JZ,&liftheight\nJS,30\nMS,10\nJ2,&inkx-10,&inky-10\nJZ,&h*2\n';
            psi = psi+1;
            
            dx = 3;
            dy = 2;
            
            for i=1:length(points)
                pts = points{i};
                for j=1:(length(pts)-1)
                    xchange = pts(j+1,1) - pts(j,1);
                    ychange = pts(j+1,2) - pts(j,2);
                    if abs(xchange)>0
                        slope = ychange/xchange;
                    else
                        slope = 1000;
                    end
                    if abs(slope) < (dy/dx)
                        if xchange>0
                            deltax = dx;
                        else
                            deltax = -dx;
                        end
                        for addx = 0:deltax:xchange
%                             fprintf('dx, x %f1, y%f1\n', pts(j,1)+addx, pts(j,2)+addx*slope);
                            pointsseq{psi} = picline(app, pts(j,1)+addx, pts(j,2)+addx*slope);
                            psi = psi+1;
                        end
                    else
                        if ychange>0
                            deltay = dy;
                        else
                            deltay = -dy;
                        end
                        slope = xchange/ychange;
                        for addy = 0:deltay:ychange
%                             fprintf('dy, x %f1, y%f1\n', pts(j,1) + addy*slope, pts(j,2) + addy);
                            pointsseq{psi} = picline(app, pts(j,1) + addy*slope, pts(j,2) + addy);
                            psi = psi+1;
                        end
                    end
                end
            end
            pointsseq{psi} = 'JZ,&liftheight\nJY, &barriery\nJX, &inkx+100\n';
            file = strcat(app.matlabdir, '\slide', num2str(slidenum), '_pics_base.txt');
            savesbp(app, pointsseq, file);
        end

        function runpicroutine(app, slidenum, basename, folder)
            command = strcat('"C:\Program Files (x86)\ShopBot\ShopBot 3\Sb3.exe" " ', app.matlabdir, '\slide', num2str(slidenum), '_pics.sbp "," ",4," ",0," "2," "," "&');
            output = ops(app);
            while output>0
                output = ops(app);
                pause(1);
            end
            system(command);
            pause(3);
            taken = false;
            output = ops(app);
            count = 1;
            while output>0 || isnan(output)
                if mod(output, 2)==1
                    o = 1;
                elseif mod(output, 4)==2
                    o = 2;
                else
                    o = 10;
                end
                switch o
                    case 1 %support material
                        if ~taken
                            im = getsnapshot(app.gras);
                            if count<10
                                strcount = strcat('00', num2str(count));
                            elseif count<100
                                strcount = strcat('0', num2str(count));
                            else
                                strcount = num2str(count);
                            end
                            imname = strcat(folder,'\sbpics\', basename, '_', strcount,'.tif');
                            fprintf(strcat(strrep(imname, '\', '\\'), '\n'));
                            imwrite(im, imname, 'tif');
                            taken = true;
                            count = count+1;
                        end
                    case 2 %ink
                        taken = false;
                end 
                output = ops(app);
            end
            fprintf('Finished taking pictures\n');
            end
    
    %---------------constructStraight--------------%


        %writezigzag is used by constructStraight
        function pointstring = writezigzag(app,lines, xi, yi, vs, ffile, z, liftheight, li, lf, dir)
            pointsseq = cell(1, length(lines)*2 + 3);
            psi = 1;
            for i=1:length(lines)
                [x1, x2] = aalt(app,i, li, lf);
                if dir==1
                    % write in x
                    xwrite = num2str(xi - x1);
                    xwrite2 = num2str(xi - x2);
                    ywrite = num2str(lines(i));
                    ywrite2 = ywrite;
                else
                    % write in y
                    xwrite = num2str(lines(i));
                    xwrite2 = xwrite;
                    ywrite = num2str(yi - x1);
                    ywrite2 = num2str(yi - x2);
                end
                if i==1
                    pointsseq{psi} = strcat('J2, ', xwrite, ', ',ywrite,'\n');
                    psi = psi+1;
                    if liftheight>0
                        pointsseq{psi} = ffile2str(app,ffile);
                        psi = psi+1;
                        pointsseq{psi} = strcat('MS,', num2str(vs),'\n');
                        psi = psi+1;
                    end
                    pointsseq{psi} = strcat('JZ, ', num2str(z),'\n');
                    psi = psi+1;
                else
                    pointsseq{psi} = strcat('M2, ', xwrite, ', ', ywrite,'\n');
                end
                psi = psi + 1;
                pointsseq{psi} = strcat('M2, ', xwrite2, ', ',ywrite2,'\n');
                psi = psi + 1;   
            end
            if liftheight>0
                pointsseq{psi} = strcat('JZ,', num2str(liftheight), '\n'); %lift during move
                psi = psi+1;
                pointsseq{psi} = ffile2str(app,0);
            end
            pointstring = strcat(pointsseq{:});
        end
        
        function pointstring = writeledzigzag(app, lines, h, liftheight, li, lf, rounds, dir)
            pointsseq = cell(rounds+2);
            pointsseq{1} = strcat(ffile2str(app,0), 'JY, ', num2str(app.withy.Value),'\nJX, ', num2str(round(app.ledx.Value)),'\n');
            for i = 1:rounds
                pointsseq{i+1} = writezigzag(app, lines, app.ledx.Value, app.ledy.Value, app.LEDvS.Value, 0, h, liftheight,li, lf, dir);
            end
            pointsseq{rounds+2} = strcat('JY, ', num2str(app.withy.Value),'\nJX, ', num2str(app.supx.Value),'\n');
            pointstring = strcat(pointsseq{:});
        end
    
        %aalt is used by writezigsag and writelines
        function [a1, a2] = aalt(~,i, y1, y2)
            if mod(i,2)==0
                a1 = y2;
                a2 = y1;
            else
                a1 = y1;
                a2 = y2;
            end
        end
        
        %--------------------
        %--------------------
        %%%CONSTRUCTWOODPILE
        %--------------------
        %--------------------
        function constructWoodpile(app)
            
            supspacing = app.Linespacing.Value;
            inkspacing = app.Linespacing.Value;
            thickness = app.ThicknessEditField_2.Value;
            spacing = thickness*inkspacing + supspacing;
            lines = app.LinesEditField_2.Value;
            supborder = [supspacing/2, supspacing/2; ...
                        lines*spacing + supspacing/2, supspacing/2; ...
                        lines*spacing + supspacing/2, lines*spacing + supspacing/2; ...
                        supspacing/2, lines*spacing+supspacing/2; ...
                        supspacing/2, supspacing/2];
                    
            ib =  supborder + (supspacing/2 + inkspacing/2)*[1, 1; -1, 1; -1, -1; 1, -1; 1, 1];
            inkborder =  zeros(5*thickness, 2);
            for i=1:thickness
                inkborder((i-1)*5+1:i*5, [1, 2]) = ib + (i-1)*inkspacing*[1, 1; -1, 1; -1, -1; 1, -1; 1, 1];
            end
                       
            lineends = [spacing + supspacing/2, (lines-1)*spacing+supspacing/2];
            suplines = lineends(1):spacing:lineends(2);
            il = lineends(1)+inkspacing/2+supspacing/2:spacing:lineends(2)-inkspacing/2-supspacing/2-inkspacing*(thickness-1);
            inklines = zeros(thickness*length(il),1);
            for i=1:thickness
                inklines((i-1)*length(il)+1:i*length(il)) = il + inkspacing*(i-1);
            end
            inklines = sort(inklines);
            
            layers = app.Layers.Value;
            h = app.Layerthickness.Value/1000;
            
            pointsseq = cell(1, 5*layers+3); %point sequence
            psi = 1; %pointsseq index
            pointsseq{psi}  = openingline(app);
            psi = psi+1;
            
            liftheight = app.lipz.Value;
            if app.layer1sup.Value
                minmax = [min(supborder(:,1)), max(supborder(:,1))];
                pointsseq{psi} = ffile2str(app,1);
                psi = psi+1;
                pointsseq{psi} = writelines(app,(minmax(1):supspacing:minmax(2)), minmax, h, h, app.supx.Value, app.supy.Value, [], app.SupportvS.Value, 1, 2);
                psi = psi+1;
                firstheight = 2*h;
                layers = layers+1;
            else
                firstheight = h;
            end
            for z = firstheight:h:layers*h
                if ~app.writesup.Value && ~app.writeled.Value
                    liftheight = z+h;
                end
                if app.writesup.Value
                    %SUPPORT LINES
                    pointsseq{psi} = writelines(app,suplines, lineends, z, h, app.supx.Value, app.supy.Value, supborder, app.SupportvS.Value, 1, liftheight);
                    psi = psi+1;
                end
                if app.writeink.Value
                    %INK LINES
                    pointsseq{psi} = writelines(app,inklines, lineends, z, h, app.inkx.Value, app.inky.Value, inkborder, app.InkvS.Value, 2, liftheight);
                    psi = psi+1;
                    %CURING led
                    if app.writeled.Value
                        pointsseq{psi} = writeledlines(app, inklines, lineends, z, h, inkborder, liftheight, 1);
                        psi = psi+1;
                    end
                end
            end
            if app.finalled.Value && ~app.writeled.Value
                pointsseq{psi} = strcat('JZ, ', num2str(app.lipz.Value), '\n');
                psi = psi+1;
                pointsseq{psi} = writeledlines(app, inklines, lineends, z, h, inkborder, app.lipz.Value, round(layers/2));
                psi = psi+1;
            end
            pointsseq{psi} = strcat(ffile2str(app,0), 'JZ, ', num2str(app.lipz.Value), '\nJX, 140\n'); %lower and draw away
            savemsbpfiles(app,pointsseq)
        end
    
        %writelines is used by constructWoodpile
        function pointstring = writelines(app,lines, lineends, z, h, x0, y0, borderpoints, vs, ffile, liftheight)
            pointsseq = cell(1, 3 + 2*length(lines) + length(borderpoints));
            psi = 1;
            %set move speed
            pointsseq{psi} = strcat('MS,', num2str(vs),'\n');
            psi = psi+1;
            %write lines
            for i=1:length(lines)
                [x1, x2] = aalt(app,i, lineends(1), lineends(2));
                if mod(z/h, 2)==0
                    y1 = lines(i);
                    y2 = y1;
                else
                    y1 = x1;
                    y2 = x2;
                    x1 = lines(i);
                    x2 = lines(i);
                end
                if i==1 
                    pointsseq{psi} = strcat('J2, ', num2str(x0 - x1), ', ', num2str(y0 - y1),'\nJZ, ', num2str(z),'\n', ffile2str(app,ffile));
                else
                    pointsseq{psi} = strcat('M2, ', num2str(x0 - x1), ', ', num2str(y0 - y1),'\n');
                end
                psi = psi + 1;
                pointsseq{psi} = strcat('M2, ', num2str(x0 - x2), ', ', num2str(y0 - y2),'\n');
                psi = psi + 1; 
            end
            pointsseq{psi} = strcat('JZ, ', num2str(z+2), '\n');
            psi = psi+1;
            if ~isempty(borderpoints)
                if x2>lineends(2)/2
                    if y2>lineends(2)/2
                        istart = 3;
                    else
                        istart = 2;
                    end
                else
                    if y2>lineends(2)/2
                        istart = 4;
                    else
                        istart = 1;
                    end
                end
                i = istart;
                pointsseq{psi} = strcat('J2, ', num2str(x0 - borderpoints(i,1)), ', ', num2str(y0 - borderpoints(i,2)),'\nJZ, ', num2str(z),'\n');
                psi = psi + 1;
                for i= [(istart+1:length(borderpoints))'; (1:istart)']'
                    pointsseq{psi} = strcat('M2, ', num2str(x0 - borderpoints(i,1)), ', ', num2str(y0 - borderpoints(i,2)),'\n');
                    psi = psi + 1;
                end
            end
            pointsseq{psi} = strcat(ffile2str(app,0), 'JZ,', num2str(z+liftheight), '\n'); %lift during move
            pointstring = strcat(pointsseq{:});
        end
    
        function pointstring = writeledlines(app, inklines, lineends, z, h, inkborder, liftheight, rounds)
            pointsseq = cell(rounds+2);
            pointsseq{1} = strcat('JY, ', num2str(app.withy.Value),'\nJX, ', num2str(round(app.ledx.Value)),'\n');
            for i=1:rounds
                pointsseq{i+1} = writelines(app,inklines, lineends, z, h, app.ledx.Value, app.ledy.Value, inkborder, app.LEDvS.Value, 0, liftheight);
            end
            pointsseq{rounds+2} = strcat('JY, ', num2str(app.withy.Value),'\nJX, ', num2str(round(app.supx.Value)),'\n');
            pointstring = strcat(pointsseq{:});
        end
    
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%
        %%%%CONSTRUCTION UTILITIES
        %ffile2str is used to determine what output flag to set
        %0: both pressures 0, 1: support pressure, 2: ink pressure
        function str = ffile2str(~,ffile)
            switch ffile
                case 0
                    str = 'SO, 1, 0\nSO, 2, 0\n';
                case 1
                    str = 'SO, 1, 1\nSO, 2, 0\n';
                case 2
                    str = 'SO, 1, 0\nSO, 2, 1\n';
                case 3
                    str = '';
            end
        end

        %savemsbpfiles saves a series of commands as a temporary file
        function savemsbpfiles(~,pointsseq)
            folder = app.matlabdir;
            sbpfile = strcat(folder, '.sbp');
            sbpcode = strcat(pointsseq{:});
            fid = fopen(sbpfile, 'w');
            fprintf(fid, sbpcode);
            fclose(fid);
        end

        function str = closingline(~)
            str = 'JX, 140\n'; %lower and draw away
        end
        
        function str = openingline(app)
            str = strcat('VC,,,20,+\nJS,100\nJZ, ', num2str(app.lipz.Value), '\n');
            
        end

        function videowrite(app, str, ts)
            if isempty(ts)
                timestamp = datestr(now,'yymmdd_HHMMSS');
            else
                timestamp = ts;
            end
            if exist(app.basedir, 'dir')>0
                dir = app.basedir;
            else
                dir = strcat(app.tempdir, '\Videos');
            end
            filename = strcat(dir, '\',str, '_', timestamp, '.mp4');
            
            switch str
                case 'sup'
                    vidtype = 'MPEG-4';
                    source = app.supcam;
                    src = app.ssrc;
                case 'led'
                    vidtype = 'MPEG-4';
                    source = app.ledcam;
                    src = app.lsrc;
                case 'gras'
                    vidtype = 'MPEG-4';
                    source = app.gras;
                    src = app.gsrc;
            end
            diskLogger = VideoWriter(filename, vidtype);
            if ischar(src.FrameRate)
                diskLogger.FrameRate = str2double(src.FrameRate);
            else
                diskLogger.FrameRate = src.FrameRate;
            end
            open(diskLogger);
            data = getdata(source, source.FramesAvailable);
            numFrames = size(data, 4);
            if numFrames>2
                for ii = 1:numFrames
                    writeVideo(diskLogger, data(:,:,:,ii));
                end
                close(diskLogger);
                fprintf('Video saved to %s\n',filename);
            else
                fprintf('No frames collected in video\n');
            end
        end

        function results = checkPositions(app)
            if (app.ledx.Value == app.supx.Value) ||(app.ledx.Value == app.supx.Value) || (app.ledx.Value == app.supx.Value) || app.withy.Value ==0 || app.lipz.Value==0
                results = true;
            else
                results = false;
            end
        end

        function renamefile(app, str, basename)
            if exist(app.basedir, 'dir')>0
                dir = app.basedir;
            else
                dir = strcat(app.tempdir, '\Videos');
            end
            filenames = ls(strcat(dir, '\', str, '_*.mp4'));
            oldfilename = strcat(dir, '\', filenames(end, :));
            newfilename = strcat(dir, '\',basename, filenames(end, :));
            movefile(oldfilename, newfilename);
        end

        function a = anglesf(~, n)
%             a = [0, 0, 60, 90, 108, 120, 0, 135]/2;
            a = 180./n;
        end

        function output = ops(~)
            output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                        'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            initializeFluigent(app);
            initializeGras(app); 
          %  winopen(app.basedir);
        end

        % Value changed function: writeled
        function writeledValueChanged(app, event)
            turnonLEDbuttons(app);
        end

        % Value changed function: layer1sup
        function layer1supValueChanged(app, event)
            turnonsupbuttons(app);
        end

        % Value changed function: writesup
        function writesupValueChanged(app, event)
            turnonsupbuttons(app);
        end

        % Value changed function: writeink
        function writeinkValueChanged(app, event)
            value = app.writeink.Value;
            if value
                app.InkvS.Enable = 'on';
                app.InkvF.Enable = 'on';
            else
                app.InkvS.Enable = 'off';
                app.InkvF.Enable = 'off';
            end
        end

        % Value changed function: finalled
        function finalledValueChanged(app, event)
            turnonLEDbuttons(app);
        end

        % Value changed function: previewgras
        function previewgrasValueChanged(app, event)
            value = app.previewgras.Value;
            gra = app.gras;
            if value
%                 vidRes = gra.VideoResolution;
%                 nBands = gra.NumberOfBands;
%                 hImage1 = image( zeros(vidRes(2), vidRes(1), nBands), 'Parent',app.grasaxes);
%                 preview(gra, hImage1);
                preview(gra);
            else
                stoppreview(app.gras);
            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            if app.fConnect
                 mfcs_set_auto(app.hn, 0, 0); %stop flow to both channels
            end
            if(checkPositions(app))
                msgbox('Check positions');
                return;
            end
            system(strcat('"C:\Program Files (x86)\ShopBot\ShopBot 3\Sb3.exe" "', app.matlabdir, '\resetswitches.sbp "," ",4," ",0," "2," "," "&'));
            scale = inkscale(app);
            prink = round(scale*(app.InkvF.Value));
            % support
            vfsup = app.SupportvF.Value;
%             prsup = round(0.476608*vfsup + 335.984*log(3.74992*vfsup)); %laponite 3%
            prsup = 42.7876*vfsup + 1032.24*log(3.04067*vfsup); %carbopol 1.2%
            if prsup>7000
                prsup = 7000;
                fprintf('Support pressure limit exceeded');
            end
            fprintf('Ink %4.0f mbar, support %4.0f mbar\n', prink, prsup);
            
            app.supp = prsup;
            app.inkp = prink;
        
            % PLAN OUT PRINT PATH
            %standoff distance
            
            changefilename = false;
            
            switch app.typetab.SelectedTab
                case app.PrismTab
                    constructPrism(app);
                case app.StraightlinesTab
                    constructStraight(app);
                case app.WoodpileTab
                    constructWoodpile(app);
                case app.DsideTab
                    construct3Dside(app);
                    changefilename = true;
                    basename = strcat('D3S_', app.sup.SelectedObject.Tooltip, '_',...
                        app.ink.SelectedObject.Tooltip, '_v', num2str(app.InkvF.Value), '_');
                case app.DlayerTab
                    construct3Dlayer(app);
                    changefilename = true;
                    basename = strcat('D3L_', app.sup.SelectedObject.Tooltip, '_',...
                        app.ink.SelectedObject.Tooltip, '_v', num2str(app.InkvF.Value), '_');
                case app.DTab
                    construct2D(app);
                    return;
            end
            if app.gorecink.Value
                app.recordgras.Value = true;
                recordgrasValueChanged(app, 0);
            end
            if app.gorecled.Value
                app.recordled.Value = true;
                recordledValueChanged(app, 0);
            end
            if app.gorecsup.Value
                app.recordsup.Value = true;
                recordsupValueChanged(app, 0);
            end
            command = strcat('"C:\Program Files (x86)\ShopBot\ShopBot 3\Sb3.exe"  " ', app.matlabdir, '\temp.sbp "," ",4," ",0," "2," "," "&');
            system(command);
            pause(10);
            output = 9;
            if app.writeled.Value
                critout = 0;
            else
                critout = 8;
            end
            % watch the switches to know when we're done
            while output>critout
                output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                        'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
                pause(5);  
%                 fprintf('%f\n',output);
            end
            % stop cameras after ink stops flowing
            if app.gorecink.Value
                app.recordgras.Value = false;
                stop(app.gras);
                fprintf('Stopping gras recording\n');
            end            
            if app.gorecsup.Value
                app.recordsup.Value = false;
                stop(app.supcam);
                fprintf('Stopping support recording\n');
            end
            % save videos after stopping cameras
            ts = datestr(now,'yymmdd_HHMMSS');
            if app.gorecink.Value
                videowrite(app, 'gras', ts);
                if changefilename
                    renamefile(app, 'gras', basename);
                end
            end            
            if app.gorecsup.Value
                videowrite(app, 'sup', ts);
                if changefilename
                    renamefile(app, 'sup', basename);
                end
            end
            % wait for LED to finish
            while output>0
                output = str2double(winqueryreg('HKEY_CURRENT_USER', ...
                        'Software\VB and VBA Program Settings\Shopbot\UserData', 'OutPutSwitches'));
                pause(5);               
            end
            if app.gorecled.Value
                app.recordled.Value = false;
                stop(app.ledcam);
                fprintf('Stopping LED recording\n');
            end
            if app.gorecled.Value
                videowrite(app, 'led', ts);
                if changefilename
                    renamefile(app, 'led', basename);
                end
            end
            fprintf('----------\nFinished\n----------\n');
        end

        % Value changed function: c1p
        function c1pValueChanged(app, event)
            value = app.c1p.Value;
            if app.fConnect
                mfcs_set_auto(app.hn, value, 1);
            else
                fprintf('Cannot set pressure because Fluigent is not connected. Connect Fluigent and restart GUI.\n');
            end
        end

        % Value changed function: c2p
        function c2pValueChanged(app, event)
            value = app.c2p.Value;
            if app.fConnect
                mfcs_set_auto(app.hn, value, 2);
            else
                fprintf('Cannot set pressure because Fluigent is not connected. Connect Fluigent and restart GUI.\n');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.timer1)
                stop(app.timer1);
            end
            delete(app);
        end

        % Value changed function: previewled
        function previewledValueChanged(app, event)
            value = app.previewled.Value;
            le = app.ledcam;
            if value
%                 vidRes = le.VideoResolution;
%                 nBands = le.NumberOfBands;
%                 hImage1 = image( zeros(vidRes(2), vidRes(1), nBands), 'Parent',app.ledaxes);
%                 preview(le, hImage1);]
                preview(le);
            else
                stoppreview(app.ledcam);
            end
        end

        % Value changed function: previewsup
        function previewsupValueChanged(app, event)
            value = app.previewsup.Value;
            su = app.supcam;
            if value
%                 vidRes = su.VideoResolution;
%                 nBands = su.NumberOfBands;
%                 hImage1 = image( zeros(vidRes(2), vidRes(1), nBands), 'Parent', app.supaxes);
%                 preview(su, hImage1);
                preview(su);
            else
                stoppreview(app.supcam);
            end
        end

        % Value changed function: recordled
        function recordledValueChanged(app, event)
            value = app.recordled.Value;
            if value
                start(app.ledcam);
                fprintf('Starting LED recording\n');
            else
                stop(app.ledcam);
                videowrite(app, 'led', '');
            end
        end

        % Value changed function: recordgras
        function recordgrasValueChanged(app, event)
            value = app.recordgras.Value;
            if value
                start(app.gras);
                fprintf('Starting gras recording\n');
            else
                stop(app.gras);
                videowrite(app, 'gras', '');
            end
        end

        % Value changed function: recordsup
        function recordsupValueChanged(app, event)
            value = app.recordsup.Value;
            if value
                start(app.supcam);
                fprintf('Starting sup recording\n');
            else
                stop(app.supcam);
                videowrite(app, 'sup', '');
            end
        end

        % Selection change function: typetab
        function typetabSelectionChanged(app, event)
            selectedTab = app.typetab.SelectedTab;
            if selectedTab==app.DsideTab || selectedTab==app.DlayerTab
                    app.gorecink.Value = true;
                    app.writeink.Value = true;
                    app.writesup.Value = false;
                    app.recordsup.Value = false;
                    app.layer1sup.Value = false;
                    app.recordled.Value = false;
                    app.finalled.Value = true;
                    app.writeled.Value = false;
                    app.InkvF.Enable = 'on';
                    app.InkvS.Enable = 'off';
                    app.LEDvS.Enable = 'on';
                    app.SupportvF.Enable = 'off';
                    app.SupportvS.Enable = 'off';
                    app.InkvS.Editable = 'off';
            elseif selectedTab==app.DTab
                    app.gorecink.Value = true;
                    app.gorecink.Enable = false;
                    app.writeink.Value = true;
                    app.gorecink.Enable = false;
                    app.writesup.Value = true;
                    app.writesup.Enable = false;
                    app.recordsup.Value = false;
                    app.recordsup.Enable = false;
                    app.layer1sup.Value = true;
                    app.layer1sup.Enable = false;
                    app.recordled.Value = false;
                    app.recordled.Enable = false;
                    app.finalled.Value = true;
                    app.finalled.Enable = false;
                    app.writeled.Value = false;
                    app.writeled.Enable = false;
                    app.InkvF.Enable = 'off';
                    app.InkvS.Enable = 'off';
                    app.LEDvS.Enable = 'on';
                    app.SupportvF.Enable = 'on';
                    app.SupportvS.Enable = 'on';
                    app.InkvS.Editable = 'off';
                    app.Layerthickness.Value = 300;
                    app.Layerthickness.Enable = 'off';
                    app.Linespacing.Value = 0.3;
                    app.Linespacing.Enable = 'off';
                    app.Layers.Value = 2;
                    app.Layers.Enable = 'off';
            else
                    app.Layerthickness.Enable = 'on';
                    app.Linespacing.Enable = 'on';
                    app.Layers.Enable = 'on';
                    app.InkvF.Enable = 'on';
                    app.InkvS.Enable = 'on';
                    app.InkvS.Editable = 'on';
                    app.SupportvF.Enable = 'on';
                    app.SupportvS.Enable = 'on';
            end
            
        end

        % Button pushed function: ConstructfilesButton
        function ConstructfilesButtonPushed(app, event)
            fprintf('constructing files\n');
            reconstructslides(app);
            fprintf('Finished\n');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InkPanel = uipanel(app.UIFigure);
            app.InkPanel.Title = 'Ink';
            app.previewgras = uibutton(app.InkPanel, 'state');
            app.previewgras.ValueChangedFcn = createCallbackFcn(app, @previewgrasValueChanged, true);
            app.previewgras.Enable = 'off';
            app.previewgras.Text = 'Preview';
            app.recordgras = uibutton(app.InkPanel, 'state');
            app.recordgras.ValueChangedFcn = createCallbackFcn(app, @recordgrasValueChanged, true);
            app.recordgras.Enable = 'off';
            app.recordgras.Text = 'Record';
            app.gorecink = uicheckbox(app.InkPanel);
            app.gorecink.Text = 'Record ink';
            app.InkvFmmsEditFieldLabel = uilabel(app.InkPanel);
            app.InkvFmmsEditFieldLabel.Text = 'Ink vF (mm/s)';
            app.InkvF = uieditfield(app.InkPanel, 'numeric');
            app.InkvF.Limits = [0 Inf];
            app.InkvF.Enable = 'off';
            app.InkvF.Value = 3;
            app.InkvSmmsEditFieldLabel = uilabel(app.InkPanel);
            app.InkvSmmsEditFieldLabel.Text = 'Ink vS (mm/s)';
            app.InkvS = uieditfield(app.InkPanel, 'numeric');
            app.InkvS.Limits = [0 50];
            app.InkvS.Enable = 'off';
            app.InkvS.Value = 3;
            app.writeink = uicheckbox(app.InkPanel);
            app.writeink.ValueChangedFcn = createCallbackFcn(app, @writeinkValueChanged, true);
            app.writeink.Text = 'Write ink';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.GoButton = uibutton(app.SettingsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.LayerthicknessumEditFieldLabel = uilabel(app.SettingsPanel);
            app.LayerthicknessumEditFieldLabel.Text = 'Layer thickness (um)';
            app.Layerthickness = uieditfield(app.SettingsPanel, 'numeric');
            app.Layerthickness.Limits = [0 2000];
            app.Layerthickness.Value = 300;
            app.LayersEditFieldLabel = uilabel(app.SettingsPanel);
            app.LayersEditFieldLabel.Text = 'Layers';
            app.Layers = uieditfield(app.SettingsPanel, 'numeric');
            app.Layers.Limits = [0 100];
            app.Layers.Value = 2;
            app.LinespacingmmLabel = uilabel(app.SettingsPanel);
            app.LinespacingmmLabel.Text = 'Line spacing (mm)';
            app.Linespacing = uieditfield(app.SettingsPanel, 'numeric');
            app.Linespacing.Limits = [0.1 10];
            app.Linespacing.Value = 0.3;
            app.ConstructfilesButton = uibutton(app.SettingsPanel, 'push');
            app.ConstructfilesButton.ButtonPushedFcn = createCallbackFcn(app, @ConstructfilesButtonPushed, true);
            app.ConstructfilesButton.Text = 'Construct files';
            app.FluigentPanel = uipanel(app.UIFigure);
            app.FluigentPanel.Title = 'Fluigent';
            app.Channel1pressurembarEditFieldLabel = uilabel(app.FluigentPanel);
            app.Channel1pressurembarEditFieldLabel.Text = 'Channel 1 pressure (mbar)';
            app.c1p = uieditfield(app.FluigentPanel, 'numeric');
            app.c1p.ValueChangedFcn = createCallbackFcn(app, @c1pValueChanged, true);
            app.c1p.Enable = 'off';
            app.Channel2pressurembarEditFieldLabel = uilabel(app.FluigentPanel);
            app.Channel2pressurembarEditFieldLabel.Text = 'Channel 2 pressure (mbar)';
            app.c2p = uieditfield(app.FluigentPanel, 'numeric');
            app.c2p.ValueChangedFcn = createCallbackFcn(app, @c2pValueChanged, true);
            app.c2p.Enable = 'off';
            app.paxes = uiaxes(app.FluigentPanel);
            xlabel(app.paxes, 'Time (s)')
            ylabel(app.paxes, 'Pressure (mbar)')
            app.paxes.YLim = [0 7200];
            app.paxes.Visible = 'off';
            app.LEDPanel = uipanel(app.UIFigure);
            app.LEDPanel.Title = 'LED';
            app.previewled = uibutton(app.LEDPanel, 'state');
            app.previewled.ValueChangedFcn = createCallbackFcn(app, @previewledValueChanged, true);
            app.previewled.Enable = 'off';
            app.previewled.Text = 'Preview';
            app.recordled = uibutton(app.LEDPanel, 'state');
            app.recordled.ValueChangedFcn = createCallbackFcn(app, @recordledValueChanged, true);
            app.recordled.Enable = 'off';
            app.recordled.Text = 'Record';
            app.gorecled = uicheckbox(app.LEDPanel);
            app.gorecled.Text = 'Record LED';
            app.finalled = uicheckbox(app.LEDPanel);
            app.finalled.ValueChangedFcn = createCallbackFcn(app, @finalledValueChanged, true);
            app.finalled.Text = 'Final LED';
            app.writeled = uicheckbox(app.LEDPanel);
            app.writeled.ValueChangedFcn = createCallbackFcn(app, @writeledValueChanged, true);
            app.writeled.Text = 'Write LED';
            app.LEDvSmmsEditFieldLabel = uilabel(app.LEDPanel);
            app.LEDvSmmsEditFieldLabel.Text = 'LED vS (mm/s)';
            app.LEDvS = uieditfield(app.LEDPanel, 'numeric');
            app.LEDvS.Limits = [0 50];
            app.LEDvS.Enable = 'off';
            app.LEDvS.Value = 20;
            app.SupportPanel = uipanel(app.UIFigure);
            app.SupportPanel.Title = 'Support';
            app.previewsup = uibutton(app.SupportPanel, 'state');
            app.previewsup.ValueChangedFcn = createCallbackFcn(app, @previewsupValueChanged, true);
            app.previewsup.Enable = 'off';
            app.previewsup.Text = 'Preview';
            app.recordsup = uibutton(app.SupportPanel, 'state');
            app.recordsup.ValueChangedFcn = createCallbackFcn(app, @recordsupValueChanged, true);
            app.recordsup.Enable = 'off';
            app.recordsup.Text = 'Record';
            app.gorecsup = uicheckbox(app.SupportPanel);
            app.gorecsup.Text = 'Record support';
            app.layer1sup = uicheckbox(app.SupportPanel);
            app.layer1sup.ValueChangedFcn = createCallbackFcn(app, @layer1supValueChanged, true);
            app.layer1sup.Text = '1st layer support';
            app.writesup = uicheckbox(app.SupportPanel);
            app.writesup.ValueChangedFcn = createCallbackFcn(app, @writesupValueChanged, true);
            app.writesup.Text = 'Write support';
            app.SupportvFmmsEditFieldLabel = uilabel(app.SupportPanel);
            app.SupportvFmmsEditFieldLabel.Text = 'Support vF (mm/s)';
            app.SupportvF = uieditfield(app.SupportPanel, 'numeric');
            app.SupportvF.Limits = [0 Inf];
            app.SupportvF.Enable = 'off';
            app.SupportvF.Value = 10;
            app.SupportvSmmsEditFieldLabel = uilabel(app.SupportPanel);
            app.SupportvSmmsEditFieldLabel.Text = 'Support vS (mm/s)';
            app.SupportvS = uieditfield(app.SupportPanel, 'numeric');
            app.SupportvS.Limits = [0 200];
            app.SupportvS.Enable = 'off';
            app.SupportvS.Value = 15;
            app.PositionsPanel = uipanel(app.UIFigure);
            app.PositionsPanel.Title = 'Positions';
            app.LEDbarrieryEditFieldLabel = uilabel(app.PositionsPanel);
            app.LEDbarrieryEditFieldLabel.Text = 'LED barrier y';
            app.withy = uieditfield(app.PositionsPanel, 'numeric');
            app.withy.Value = -120;
            app.StagelipzEditFieldLabel = uilabel(app.PositionsPanel);
            app.StagelipzEditFieldLabel.Text = 'Stage lip z';
            app.lipz = uieditfield(app.PositionsPanel, 'numeric');
            app.lipz.Value = 10;
            app.ledy = uieditfield(app.PositionsPanel, 'numeric');
            app.ledy.Value = -13.54;
            app.LEDxyEditFieldLabel = uilabel(app.PositionsPanel);
            app.LEDxyEditFieldLabel.Text = 'LED x,y';
            app.ledx = uieditfield(app.PositionsPanel, 'numeric');
            app.ledx.Value = -186.8;
            app.supy = uieditfield(app.PositionsPanel, 'numeric');
            app.SupportxyEditFieldLabel = uilabel(app.PositionsPanel);
            app.SupportxyEditFieldLabel.Text = 'Support x,y';
            app.supx = uieditfield(app.PositionsPanel, 'numeric');
            app.inky = uieditfield(app.PositionsPanel, 'numeric');
            app.InkxyEditFieldLabel = uilabel(app.PositionsPanel);
            app.InkxyEditFieldLabel.Text = 'Ink x,y';
            app.inkx = uieditfield(app.PositionsPanel, 'numeric');
            app.inkx.Value = 108.2;
            app.ink = uibuttongroup(app.UIFigure);
            app.ink.Title = 'Ink (mbar/mm/s)';
            app.u830 = uiradiobutton(app.ink);
            app.u830.Tooltip = 'u830';
            app.u830.Text = '8/30 (29.83)';
            app.u830.Value = true;
            app.u820 = uiradiobutton(app.ink);
            app.u820.Tooltip = {'u820'};
            app.u820.Text = '8/20 (129)';
            app.u825 = uiradiobutton(app.ink);
            app.u825.Tooltip = {'u825'};
            app.u825.Text = '8/25 (120)';
            app.u835 = uiradiobutton(app.ink);
            app.u835.Tooltip = {'u835'};
            app.u835.Text = '8/35 (37.76)';
            app.typetab = uitabgroup(app.UIFigure);
            app.typetab.SelectionChangedFcn = createCallbackFcn(app, @typetabSelectionChanged, true);
            app.PrismTab = uitab(app.typetab);
            app.PrismTab.Title = 'Prism';
            app.DiameterEditFieldLabel = uilabel(app.PrismTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.PrismTab, 'numeric');
            app.DiameterEditField.Limits = [0 100];
            app.DiameterEditField.Value = 10;
            app.EdgesEditFieldLabel = uilabel(app.PrismTab);
            app.EdgesEditFieldLabel.Text = 'Edges';
            app.EdgesEditField = uieditfield(app.PrismTab, 'numeric');
            app.EdgesEditField.Limits = [3 100];
            app.EdgesEditField.Value = 8;
            app.ThicknessEditFieldLabel = uilabel(app.PrismTab);
            app.ThicknessEditFieldLabel.Text = 'Thickness';
            app.ThicknessEditField = uieditfield(app.PrismTab, 'numeric');
            app.ThicknessEditField.Limits = [1 10];
            app.ThicknessEditField.Value = 3;
            app.StraightlinesTab = uitab(app.typetab);
            app.StraightlinesTab.Title = 'Straight lines';
            app.LinesEditFieldLabel = uilabel(app.StraightlinesTab);
            app.LinesEditFieldLabel.Text = 'Lines';
            app.LinesEditField = uieditfield(app.StraightlinesTab, 'numeric');
            app.LinesEditField.Limits = [0 100];
            app.LinesEditField.Value = 9;
            app.LengthEditFieldLabel = uilabel(app.StraightlinesTab);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.StraightlinesTab, 'numeric');
            app.LengthEditField.Limits = [3 100];
            app.LengthEditField.Value = 35;
            app.WritedirectionSwitchLabel = uilabel(app.StraightlinesTab);
            app.WritedirectionSwitchLabel.Text = 'Write direction';
            app.WritedirectionSwitch = uiswitch(app.StraightlinesTab, 'slider');
            app.WritedirectionSwitch.Items = {'x', 'y'};
            app.WritedirectionSwitch.ItemsData = {'1', '2'};
            app.WritedirectionSwitch.Value = '2';
            app.WoodpileTab = uitab(app.typetab);
            app.WoodpileTab.Title = 'Woodpile';
            app.LinesEditField_2Label = uilabel(app.WoodpileTab);
            app.LinesEditField_2Label.Text = 'Lines';
            app.LinesEditField_2 = uieditfield(app.WoodpileTab, 'numeric');
            app.LinesEditField_2.Limits = [0 100];
            app.LinesEditField_2.Value = 9;
            app.ThicknessEditField_2Label = uilabel(app.WoodpileTab);
            app.ThicknessEditField_2Label.Text = 'Thickness';
            app.ThicknessEditField_2 = uieditfield(app.WoodpileTab, 'numeric');
            app.ThicknessEditField_2.Limits = [1 10];
            app.ThicknessEditField_2.Value = 1;
            app.DsideTab = uitab(app.typetab);
            app.DsideTab.Title = '3D side';
            app.DlayerTab = uitab(app.typetab);
            app.DlayerTab.Title = '3D layer';
            app.DTab = uitab(app.typetab);
            app.DTab.Title = '2D';
            app.MaxvSinkEditFieldLabel = uilabel(app.DTab);
            app.MaxvSinkEditFieldLabel.Text = 'Max vS ink';
            app.MaxvSink = uieditfield(app.DTab, 'numeric');
            app.MaxvSink.Value = 12;
            app.MinvSinkEditFieldLabel = uilabel(app.DTab);
            app.MinvSinkEditFieldLabel.Text = 'Min vS ink';
            app.MinvSink = uieditfield(app.DTab, 'numeric');
            app.MinvSink.Value = 3;
            app.minslideLabel = uilabel(app.DTab);
            app.minslideLabel.Text = 'min slide';
            app.minslide = uieditfield(app.DTab, 'numeric');
            app.minslide.Value = 1;
            app.maxslideLabel = uilabel(app.DTab);
            app.maxslideLabel.Text = 'max slide';
            app.maxslide = uieditfield(app.DTab, 'numeric');
            app.maxslide.Value = 4;
            app.dvSLabel = uilabel(app.DTab);
            app.dvSLabel.Text = 'dvS';
            app.dvs = uieditfield(app.DTab, 'numeric');
            app.dvs.Value = 3;
            app.linesLabel = uilabel(app.DTab);
            app.linesLabel.Text = 'lines';
            app.lines = uieditfield(app.DTab, 'numeric');
            app.lines.Value = 3;
            app.TypeDropDownLabel = uilabel(app.DTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.runtype = uidropdown(app.DTab);
            app.runtype.Items = {'2D', '3D', '3deep'};
            app.runtype.Value = '3D';
            app.sup = uibuttongroup(app.UIFigure);
            app.sup.Title = 'Support';
            app.c04 = uiradiobutton(app.sup);
            app.c04.Tooltip = 'c04';
            app.c04.Text = 'Carbopol 0.4';
            app.c04.Value = true;
            app.c12 = uiradiobutton(app.sup);
            app.c12.Tooltip = 'c12';
            app.c12.Text = 'Carbopol 1.2';
            app.c20 = uiradiobutton(app.sup);
            app.c20.Tooltip = 'c20';
            app.c20.Text = 'Carbopol 2.0';
            app.l3 = uiradiobutton(app.sup);
            app.l3.Tooltip = 'l3';
            app.l3.Text = 'Laponite 3';
            app.l4 = uiradiobutton(app.sup);
            app.l4.Tooltip = 'l4';
            app.l4.Text = 'Laponite 4';
        end
    end

    methods (Access = public)

        % Construct app
        function app = supportgui2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end